classdef Scheduler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConnecttheAnimaticsSwitch       matlab.ui.control.RockerSwitch
        ConnecttheAnimaticsSwitchLabel  matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        ChargeAnimaticCheckBox          matlab.ui.control.CheckBox
        didtCheckBox                    matlab.ui.control.CheckBox
        CurrentCheckBox                 matlab.ui.control.CheckBox
        ChargeCheckBox                  matlab.ui.control.CheckBox
        MEditField_2                    matlab.ui.control.NumericEditField
        MEditFieldLabel_2               matlab.ui.control.Label
        KEditField_2                    matlab.ui.control.NumericEditField
        KEditFieldLabel_2               matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        MassAnimaticCheckBox            matlab.ui.control.CheckBox
        AccelerationCheckBox            matlab.ui.control.CheckBox
        VelocityCheckBox                matlab.ui.control.CheckBox
        DisplacementCheckBox            matlab.ui.control.CheckBox
        MoveMassThroughTimeSwitch       matlab.ui.control.Switch
        MoveMassThroughTimeSwitchLabel  matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        GraphButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.UIAxes.XGrid = 1;
          app.UIAxes.YGrid = 1;
          app.UIAxes.ZGrid = 1;
          hold(app.UIAxes);
        
          
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            omega = sqrt(app.KEditField.Value/app.MEditField.Value);
            t = linspace(0, 4*2*pi, 100);
            z = 10*cos(omega*t);
            y = 0*t + 1.5;
            y_2 = 0*t + 0.5;
            y_3 = 0*t + 2.5;
            
            omegaLC = 1/(sqrt(app.KEditField_2.Value*app.MEditField_2.Value));
            zNew = 10*cos(omegaLC*t);
            zNew2 = -1*10*omegaLC*sin(omegaLC*t);
            zNew3 = -1*10*omegaLC*omegaLC*cos(omegaLC*t);
            
            value = app.MoveMassThroughTimeSwitch.Value;
            
            if  strcmp(value, 'On')
                x = t;
            else
                x = t*0;
            end
            z_2 = -1*10*omega*sin(omega*t);
            z_3 = -1*10*omega*omega*cos(omega*t);
        
            
            for k=1:length(t)
               clear app.UIAxes;
                
               t_k = t(k);
               x_t = x(k);
               y_t = y(k);
               z_t = z(k);
               
               zNew4 = zNew(k);
               
               y_t2 = y_2(k) + 2
               
               view(app.UIAxes, ([35, 30]));
               if app.ChargeAnimaticCheckBox.Value == 1 && strcmp(value, 'On')
                     plot3(app.UIAxes, x_t, y_t2, zNew4, "ms", "LineWidth", 3);
               end
               if app.MassAnimaticCheckBox.Value == 1 || app.MassAnimaticCheckBox.Enable == 0
                    plot3(app.UIAxes, x_t, y_t, z_t, "ms", "LineWidth", 3);
               end


               if app.DisplacementCheckBox.Value == 1  || app.DisplacementCheckBox.Enable == 0
                   displacement = plot3(app.UIAxes, x, y, z, "black", "LineWidth", 1, "MarkerSize", 15);
               end
               if  strcmp(value, 'On') 
                 if app.VelocityCheckBox.Value == 1
                 velocity = plot3(app.UIAxes, x, y, z_2, "blue", "LineWidth", 1, "MarkerSize", 15);
                 end
                 if app.AccelerationCheckBox.Value == 1
                 acceleration = plot3(app.UIAxes, x, y, z_3, "red", "LineWidth", 1, "MarkerSize", 15);
                 end
               
                 if app.ChargeCheckBox.Value == 1
                     Charge = plot3(app.UIAxes, x, y_3, zNew, "black", "LineWidth", 1, "MarkerSize", 15);
                 end
                 
                 if app.CurrentCheckBox.Value == 1
                     Current = plot3(app.UIAxes, x, y_3, zNew2, "blue", "LineWidth", 1, "MarkerSize", 15);  
                 end
                 
                 if app.didtCheckBox.Value == 1
                     didt = plot3(app.UIAxes, x, y_3, zNew3, "red", "LineWidth", 1, "MarkerSize", 15); 
                 end
                 if app.AccelerationCheckBox.Value == 0 && app.VelocityCheckBox.Value == 0 && app.DisplacementCheckBox.Value == 0 && app.ChargeCheckBox.Value == 0 && app.CurrentCheckBox.Value == 0 && app.didtCheckBox.Value == 0
                     displacement = plot3(app.UIAxes, x, y, z, "white", "LineWidth", 1, "MarkerSize", 15);
                 end
                 
                   
               end
               if omega == omegaLC && app.MassAnimaticCheckBox.Value == 1 && app.ChargeAnimaticCheckBox.Value == 1 && strcmp(app.ConnecttheAnimaticsSwitch.Value, 'On')


                 for g=1.5:0.1:2.5
                     xConnector = x_t;
                     zConnector = z_t;
                     yConnector = g;
                     plot3(app.UIAxes, xConnector, yConnector, zConnector, 'm_' ,"LineWidth", 3);
                     pause(0.000000000000000000000000000000000000000000000000000000000000000000001);
                 end
               end
               app.UIAxes.XLabel.String = "time";
               app.UIAxes.ZLabel.String = "displacement (x10)";
               app.UIAxes.YLabel.String = "";
               app.UIAxes.Title.String = (['Mass at t = ', num2str(t_k), ' seconds']);
               

               view(app.UIAxes, ([35 30]));


               pause(0.00000000000000000000000000000000001);
               movieVector(k) = getframe(app.UIFigure);
               if k ~= length(t)
                 cla(app.UIAxes);
               end
              
            end

            myWriter = VideoWriter('PartOne', "MPEG-4");
            open(myWriter);
            writeVideo(myWriter, movieVector);
            close(myWriter);
            
        end

        % Value changed function: MoveMassThroughTimeSwitch
        function MoveMassThroughTimeSwitchValueChanged(app, event)
            value = app.MoveMassThroughTimeSwitch.Value;
            if strcmp(value, 'Off')
                app.VelocityCheckBox.Enable = 0;
                app.AccelerationCheckBox.Enable = 0;
                app.MassAnimaticCheckBox.Enable = 0;
                app.DisplacementCheckBox.Enable = 0;
                app.ChargeCheckBox.Enable = 0;
                app.CurrentCheckBox.Enable = 0;
                app.didtCheckBox.Enable = 0;
                app.ChargeAnimaticCheckBox.Enable = 0;
            
            else
                app.VelocityCheckBox.Enable = 1;
                app.AccelerationCheckBox.Enable = 1;
                app.MassAnimaticCheckBox.Enable = 1;
                app.DisplacementCheckBox.Enable = 1;
                app.ChargeCheckBox.Enable = 1;
                app.CurrentCheckBox.Enable = 1;
                app.didtCheckBox.Enable = 1;
                app.ChargeAnimaticCheckBox.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mass through time')
            xlabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Displacement')
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveMassThroughTimeSwitchLabel = uilabel(app.UIFigure);
            app.MoveMassThroughTimeSwitchLabel.Text = 'Move Mass Through Time ';
            app.MoveMassThroughTimeSwitch = uiswitch(app.UIFigure, 'slider');
            app.MoveMassThroughTimeSwitch.Items = {'On', 'Off'};
            app.MoveMassThroughTimeSwitch.ValueChangedFcn = createCallbackFcn(app, @MoveMassThroughTimeSwitchValueChanged, true);
            app.MoveMassThroughTimeSwitch.Value = 'On';
            app.DisplacementCheckBox = uicheckbox(app.UIFigure);
            app.DisplacementCheckBox.Text = 'Displacement';
            app.DisplacementCheckBox.Value = true;
            app.VelocityCheckBox = uicheckbox(app.UIFigure);
            app.VelocityCheckBox.Text = 'Velocity';
            app.VelocityCheckBox.Value = true;
            app.AccelerationCheckBox = uicheckbox(app.UIFigure);
            app.AccelerationCheckBox.Text = 'Acceleration';
            app.AccelerationCheckBox.Value = true;
            app.MassAnimaticCheckBox = uicheckbox(app.UIFigure);
            app.MassAnimaticCheckBox.Text = 'Mass Animatic';
            app.MassAnimaticCheckBox.Value = true;
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.KEditFieldLabel_2 = uilabel(app.UIFigure);
            app.KEditFieldLabel_2.Text = 'L';
            app.KEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MEditFieldLabel_2.Text = 'C';
            app.MEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ChargeCheckBox = uicheckbox(app.UIFigure);
            app.ChargeCheckBox.Text = 'Charge';
            app.CurrentCheckBox = uicheckbox(app.UIFigure);
            app.CurrentCheckBox.Text = 'Current';
            app.didtCheckBox = uicheckbox(app.UIFigure);
            app.didtCheckBox.Text = 'di/dt';
            app.ChargeAnimaticCheckBox = uicheckbox(app.UIFigure);
            app.ChargeAnimaticCheckBox.Text = 'Charge Animatic';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.ConnecttheAnimaticsSwitchLabel = uilabel(app.UIFigure);
            app.ConnecttheAnimaticsSwitchLabel.Text = 'Connect the Animatics';
            app.ConnecttheAnimaticsSwitch = uiswitch(app.UIFigure, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scheduler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end